classdef user_changepoint_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        ExperimentTable      matlab.ui.control.Table
        CenterPanel          matlab.ui.container.Panel
        SessionTable         matlab.ui.control.Table
        GestureTable         matlab.ui.control.Table
        ExpLabel             matlab.ui.control.Label
        ExpHeader            matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        FeatureAxis          matlab.ui.control.UIAxes
        TKEOAxis             matlab.ui.control.UIAxes
        OnsetEditFieldLabel  matlab.ui.control.Label
        OnsetField           matlab.ui.control.NumericEditField
        OffsetLabel          matlab.ui.control.Label
        OffsetField          matlab.ui.control.NumericEditField
        SaveButton           matlab.ui.control.Button
        PredAxis             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Experiment
        Trials
        expIdx
        sessIdx
        gestIdx
    end
    
    methods (Access = private)
        
        function proc = tkeo_emg(~,data)
            data = highpass(data,0.1,500);
            a = data.^2;
            b = [data(1,:); data(1:end-1,:)];
            c = [data(2:end,:); data(end,:)];
            proc = abs(a - (b.*c));
            proc = lowpass(proc,5,1000);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            expFiles = dir('~/Research/flexemg_cont/flexemg_rebuttal/realtime_experiments_chgpts/*.mat');
            app.ExperimentTable.Data = {expFiles.name}';
            app.SaveButton.Enable = false;
        end

        % Cell selection callback: ExperimentTable
        function ExperimentTableCellSelection(app, event)
            indices = event.Indices;
            app.ExpLabel.Text = app.ExperimentTable.Data{indices(1)}(1:end-4);
            app.expIdx = indices(1);
            
            app.Experiment = load(['~/Research/flexemg_cont/flexemg_rebuttal/realtime_experiments_chgpts/' app.ExperimentTable.Data{indices(1)}],'experiment');
            app.Experiment = app.Experiment.experiment;
            app.SessionTable.Data = {app.Experiment.description}';
            
            cla(app.TKEOAxis)
            cla(app.FeatureAxis)
            cla(app.PredAxis)
            app.GestureTable.Data = [];
            app.SaveButton.Enable = false;
        end

        % Cell selection callback: SessionTable
        function SessionTableCellSelection(app, event)
            indices = event.Indices;
            app.Trials = app.Experiment(indices(1)).trials;
            app.GestureTable.Data = cellstr(num2str([app.Trials.gesture]'));
            app.sessIdx = indices(1);
            
            cla(app.TKEOAxis)
            cla(app.FeatureAxis)
            cla(app.PredAxis)
            app.SaveButton.Enable = false;
        end

        % Cell selection callback: GestureTable
        function GestureTableCellSelection(app, event)
            indices = event.Indices;
            app.gestIdx = indices(1);
            trial = app.Trials(indices(1));
            tk = tkeo_emg(app,trial.raw);
            
            cla(app.TKEOAxis)
            cla(app.FeatureAxis)
            cla(app.PredAxis)
            
%             p = plot(app.TKEOAxis,tk);
%             for i = 1:length(p)
%                 p(i).Color(4) = 0.07;
%             end
%             hold(app.TKEOAxis,'on');
            plot(app.TKEOAxis,rms(tk,2),'k','LineWidth',2)
%             hold(app.TKEOAxis,'off');
            axis(app.TKEOAxis, 'tight');
%             ylim(app.TKEOAxis,[0 1.2*max(rms(tk,2))])
            imagesc(app.FeatureAxis,[1 length(trial.raw)],[1 64],trial.features',[0 64])
            axis(app.FeatureAxis, 'tight');
            
            plot(app.PredAxis,(1:length(trial.prediction)).*50,trial.prediction)
            axis(app.PredAxis, 'tight');
            ylim(app.PredAxis, [99 113]);
            
            xline(app.TKEOAxis,trial.onsetTKEOMean*50,':g','LineWidth',1)
            xline(app.TKEOAxis,trial.onsetTKEOMed*50,':g','LineWidth',1)
            xline(app.TKEOAxis,trial.onsetMat*50,':g','LineWidth',1)
            xline(app.TKEOAxis,trial.onsetZoeMean*50,':g','LineWidth',1)
            xline(app.TKEOAxis,trial.onsetZoeMed*50,':g','LineWidth',1)
            
            xline(app.TKEOAxis,trial.offsetTKEOMean*50,':r','LineWidth',1)
            xline(app.TKEOAxis,trial.offsetTKEOMed*50,':r','LineWidth',1)
            xline(app.TKEOAxis,trial.offsetMat*50,':r','LineWidth',1)
            xline(app.TKEOAxis,trial.offsetZoeMean*50 + 3000,':r','LineWidth',1)
            xline(app.TKEOAxis,trial.offsetZoeMed*50 + 3000,':r','LineWidth',1)
            
            xline(app.FeatureAxis,trial.onsetTKEOMean*50,':g','LineWidth',1)
            xline(app.FeatureAxis,trial.onsetTKEOMed*50,':g','LineWidth',1)
            xline(app.FeatureAxis,trial.onsetMat*50,':g','LineWidth',1)
            xline(app.FeatureAxis,trial.onsetZoeMean*50,':g','LineWidth',1)
            xline(app.FeatureAxis,trial.onsetZoeMed*50,':g','LineWidth',1)
            
            xline(app.FeatureAxis,trial.offsetTKEOMean*50,':r','LineWidth',1)
            xline(app.FeatureAxis,trial.offsetTKEOMed*50,':r','LineWidth',1)
            xline(app.FeatureAxis,trial.offsetMat*50,':r','LineWidth',1)
            xline(app.FeatureAxis,trial.offsetZoeMean*50 + 3000,':r','LineWidth',1)
            xline(app.FeatureAxis,trial.offsetZoeMed*50 + 3000,':r','LineWidth',1)
            
            xline(app.PredAxis,trial.onsetTKEOMean*50,':g','LineWidth',1)
            xline(app.PredAxis,trial.onsetTKEOMed*50,':g','LineWidth',1)
            xline(app.PredAxis,trial.onsetMat*50,':g','LineWidth',1)
            xline(app.PredAxis,trial.onsetZoeMean*50,':g','LineWidth',1)
            xline(app.PredAxis,trial.onsetZoeMed*50,':g','LineWidth',1)
            
            xline(app.PredAxis,trial.offsetTKEOMean*50,':r','LineWidth',1)
            xline(app.PredAxis,trial.offsetTKEOMed*50,':r','LineWidth',1)
            xline(app.PredAxis,trial.offsetMat*50,':r','LineWidth',1)
            xline(app.PredAxis,trial.offsetZoeMean*50 + 3000,':r','LineWidth',1)
            xline(app.PredAxis,trial.offsetZoeMed*50 + 3000,':r','LineWidth',1)
            
            app.OnsetField.Value = -1;
            if isfield(trial,'onsetUser')
                if trial.onsetUser > 0
                    app.OnsetField.Value = trial.onsetUser;
                    xline(app.TKEOAxis,trial.onsetUser,'--g','LineWidth',3)
                    xline(app.FeatureAxis,trial.onsetUser,'--g','LineWidth',3)
                    xline(app.PredAxis,trial.onsetUser,'--g','LineWidth',3)
                end
            end
            
            app.OffsetField.Value = -1;
            if isfield(trial,'offsetUser')
                if trial.offsetUser > 0
                    app.OffsetField.Value = trial.offsetUser;
                    xline(app.TKEOAxis,trial.offsetUser,'--r','LineWidth',3)
                    xline(app.FeatureAxis,trial.offsetUser,'--r','LineWidth',3)
                    xline(app.PredAxis,trial.offsetUser,'--r','LineWidth',3)
                end
            end
            
            app.SaveButton.Enable = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Experiment(app.sessIdx).trials(app.gestIdx).onsetUser = app.OnsetField.Value;
            app.Experiment(app.sessIdx).trials(app.gestIdx).offsetUser = app.OffsetField.Value;
            app.Trials = app.Experiment(app.sessIdx).trials;
            experiment = app.Experiment;
            save(app.ExperimentTable.Data{app.expIdx},'experiment');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {1051, 1051, 1051};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {1051, 1051};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {144, '1x', 1233};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExperimentTable = uitable(app.LeftPanel);
            app.ExperimentTable.ColumnName = {'Experiment Files'};
            app.ExperimentTable.RowName = {};
            app.ExperimentTable.CellSelectionCallback = createCallbackFcn(app, @ExperimentTableCellSelection, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.SessionTable = uitable(app.CenterPanel);
            app.SessionTable.ColumnName = {'Sessions'};
            app.SessionTable.RowName = {};
            app.SessionTable.CellSelectionCallback = createCallbackFcn(app, @SessionTableCellSelection, true);
            app.GestureTable = uitable(app.CenterPanel);
            app.GestureTable.ColumnName = {'Gestures'};
            app.GestureTable.RowName = {};
            app.GestureTable.CellSelectionCallback = createCallbackFcn(app, @GestureTableCellSelection, true);
            app.ExpLabel = uilabel(app.CenterPanel);
            app.ExpHeader = uilabel(app.CenterPanel);
            app.ExpHeader.Text = 'Exp:';
            app.RightPanel = uipanel(app.GridLayout);
            app.FeatureAxis = uiaxes(app.RightPanel);
            title(app.FeatureAxis, '')
            xlabel(app.FeatureAxis, 'Time (samples)')
            ylabel(app.FeatureAxis, '')
            app.FeatureAxis.YTick = [];
            app.FeatureAxis.YTickLabel = '';
            app.TKEOAxis = uiaxes(app.RightPanel);
            title(app.TKEOAxis, '')
            xlabel(app.TKEOAxis, 'Time (samples)')
            ylabel(app.TKEOAxis, '')
            app.TKEOAxis.YTick = [];
            app.TKEOAxis.YTickLabel = '';
            app.OnsetEditFieldLabel = uilabel(app.RightPanel);
            app.OnsetEditFieldLabel.Text = 'Onset';
            app.OnsetField = uieditfield(app.RightPanel, 'numeric');
            app.OnsetField.Value = -1;
            app.OffsetLabel = uilabel(app.RightPanel);
            app.OffsetLabel.Text = 'Offset';
            app.OffsetField = uieditfield(app.RightPanel, 'numeric');
            app.OffsetField.Value = -1;
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save values';
            app.PredAxis = uiaxes(app.RightPanel);
            title(app.PredAxis, '')
            xlabel(app.PredAxis, 'Time (samples)')
            ylabel(app.PredAxis, '')
            app.PredAxis.YTick = [];
            app.PredAxis.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user_changepoint_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end